classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        VisualisationdusignalTab    matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        FiltragedusignalTab         matlab.ui.container.Tab
        LoadfileButton              matlab.ui.control.Button
        NoisereductionlevelSpinnerLabel  matlab.ui.control.Label
        NoisereductionlevelSpinner  matlab.ui.control.Spinner
        WindowmethodButtonGroup     matlab.ui.container.ButtonGroup
        HammingButton               matlab.ui.control.RadioButton
        HanButton                   matlab.ui.control.RadioButton
        BartlettButton              matlab.ui.control.RadioButton
        PlayPauseButton             matlab.ui.control.StateButton
        PerformanceTab              matlab.ui.container.Tab
    end


    properties (Access = private)
        signal          % line matrix holding loaded signal
        noise_level     % variable holding the noise level from user
        window_type     % The window chosen by user
    end


    methods (Access = private)

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            data = struct2cell(load(strcat(path, file)));
            app.signal = data{1}.';
        end

        % Selection changed function: WindowmethodButtonGroup
        function WindowmethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.WindowmethodButtonGroup.SelectedObject;
            
            disp(selectedButton);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.VisualisationdusignalTab = uitab(app.TabGroup);
            app.VisualisationdusignalTab.Title = 'Visualisation du signal';
            app.UIAxes = uiaxes(app.VisualisationdusignalTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.FiltragedusignalTab = uitab(app.TabGroup);
            app.FiltragedusignalTab.Title = 'Filtrage du signal';
            app.LoadfileButton = uibutton(app.FiltragedusignalTab, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.NoisereductionlevelSpinnerLabel = uilabel(app.FiltragedusignalTab);
            app.NoisereductionlevelSpinnerLabel.Text = 'Noise reduction level';
            app.NoisereductionlevelSpinner = uispinner(app.FiltragedusignalTab);
            app.NoisereductionlevelSpinner.Step = 0.25;
            app.NoisereductionlevelSpinner.Limits = [0 Inf];
            app.NoisereductionlevelSpinner.Value = 3;
            app.WindowmethodButtonGroup = uibuttongroup(app.FiltragedusignalTab);
            app.WindowmethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowmethodButtonGroupSelectionChanged, true);
            app.WindowmethodButtonGroup.Title = 'Window method';
            app.HammingButton = uiradiobutton(app.WindowmethodButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HammingButton.Value = true;
            app.HanButton = uiradiobutton(app.WindowmethodButtonGroup);
            app.HanButton.Text = 'Han';
            app.BartlettButton = uiradiobutton(app.WindowmethodButtonGroup);
            app.BartlettButton.Text = 'Bartlett';
            app.PlayPauseButton = uibutton(app.FiltragedusignalTab, 'state');
            app.PlayPauseButton.Text = 'Play / Pause';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end